classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI_BubbleAnalyser              matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenfileMenu                    matlab.ui.container.Menu
        ChoosefolderMenu                matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        matformatMenu                   matlab.ui.container.Menu
        csvformatMenu                   matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        UndoMenu                        matlab.ui.container.Menu
        RedoMenu                        matlab.ui.container.Menu
        CutMenu                         matlab.ui.container.Menu
        CopyMenu                        matlab.ui.container.Menu
        PasteMenu                       matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        PreviousMenu                    matlab.ui.container.Menu
        NextMenu                        matlab.ui.container.Menu
        WindowMenu                      matlab.ui.container.Menu
        MinimiseMenu                    matlab.ui.container.Menu
        FullscreenMenu                  matlab.ui.container.Menu
        ZoomMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        LicenseMenu                     matlab.ui.container.Menu
        DocumentationMenu               matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        SelectfolderTab                 matlab.ui.container.Tab
        b_FolderSelect                  matlab.ui.control.StateButton
        DirectoryLocationLabel          matlab.ui.control.Label
        t_images                        matlab.ui.control.Table
        im_SampleImg                    matlab.ui.control.Image
        b_Tab1Next                      matlab.ui.control.Button
        ImagepreviewLabel               matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ResolutionandbackgroundTab      matlab.ui.container.Tab
        b_Tab2Next                      matlab.ui.control.Button
        b_Tab2Back                      matlab.ui.control.Button
        im_pxresolution                 matlab.ui.control.Image
        im_background                   matlab.ui.control.Image
        b_ResolutionConfirm             matlab.ui.control.Button
        b_BackgroundConfirm             matlab.ui.control.Button
        PixelresolutioncalibrationLabel  matlab.ui.control.Label
        BackgroundcorrectionimageLabel  matlab.ui.control.Label
        ImagenameLabel                  matlab.ui.control.Label
        et_pxresolution                 matlab.ui.control.EditField
        orLabel                         matlab.ui.control.Label
        et_background                   matlab.ui.control.EditField
        b_ManualResolution              matlab.ui.control.Button
        l_Tab2Info                      matlab.ui.control.Label
        ImagenameLabel_2                matlab.ui.control.Label
        b_ResolutionSelect_2            matlab.ui.control.StateButton
        orLabel_2                       matlab.ui.control.Label
        b_BackgroundSelect_3            matlab.ui.control.StateButton
        orLabel_3                       matlab.ui.control.Label
        PreviewLabel                    matlab.ui.control.Label
        PreviewLabel_2                  matlab.ui.control.Label
        STEP3Tab                        matlab.ui.container.Tab
        im_processed                    matlab.ui.control.Image
        im_sample                       matlab.ui.control.Image
        BackButtonSTEP3                 matlab.ui.control.Button
        b_Tab3Next                      matlab.ui.control.Button
        b_Tab3ProcessSample             matlab.ui.control.Button
        rbg_usedefaultparams            matlab.ui.container.ButtonGroup
        UsingdefaultparametersButton    matlab.ui.control.RadioButton
        ChoosingmyownparametersButton   matlab.ui.control.RadioButton
        b_batchprocess                  matlab.ui.control.Button
        SampleimageLabel                matlab.ui.control.Label
        ProcessedimageLabel             matlab.ui.control.Label
        l_Tab3Info                      matlab.ui.control.Label
        t_paramstruct                   matlab.ui.control.Table
        dd_algorithmpick                matlab.ui.control.DropDown
        ResultsTab                      matlab.ui.container.Tab
        b_Tab4Next                      matlab.ui.control.Button
        BackButtonSTEP4                 matlab.ui.control.Button
        cb_CDF                          matlab.ui.control.CheckBox
        cb_PDF                          matlab.ui.control.CheckBox
        dmeanCheckBox                   matlab.ui.control.CheckBox
        cb_d32                          matlab.ui.control.CheckBox
        SelectoutputsLabel              matlab.ui.control.Label
        NumberofbinsEditField_2Label    matlab.ui.control.Label
        et_NumberofBins                 matlab.ui.control.EditField
        dxyCheckBox                     matlab.ui.control.CheckBox
        xEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.EditField
        yEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.EditField
        ResultsLabel                    matlab.ui.control.Label
        rbg_HistogramBy                 matlab.ui.container.ButtonGroup
        NumberButton                    matlab.ui.control.RadioButton
        VolumeButton                    matlab.ui.control.RadioButton
        Averaged32Label                 matlab.ui.control.Label
        ax_histogram                    matlab.ui.control.UIAxes
        cm_StartAnalysis                matlab.ui.container.ContextMenu
        WhatisthisMenu                  matlab.ui.container.Menu
        cm_FolderSelect                 matlab.ui.container.ContextMenu
        WhatisthisMenu_2                matlab.ui.container.Menu
        cm_TableImages                  matlab.ui.container.ContextMenu
        WhatisthisMenu_3                matlab.ui.container.Menu
    end

    % Author: Reyes, Francisco; Quintanilla, Paulina; Mesa, Diego
    % email: f.reyes@uq.edu.au,
    % Website: https://gitlab.com/frreyes1/bubble-sizer
    % Copyright Oct-2020;
    %
    %This file is part of Bubble Sizer.
    %
    %    Bubble Sizer is free software: you can redistribute it and/or modify
    %    it under the terms of the GNU General Public License as published by
    %    the Free Software Foundation version 3 only of the License.
    %
    %    Bubble Sizer is distributed in the hope that it will be useful,
    %    but WITHOUT ANY WARRANTY; without even the implied warranty of
    %    MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %    GNU General Public License for more details.
    %
    %    You should have received a copy of the GNU General Public License
    %    along with Bubble Sizer.  If not, see <https://www.gnu.org/licenses/>.
    %
    %------------- BEGIN CODE --------------
    properties (Access = private)
        working_folder_path; % Store user selected folder with the images
        quan_folder; %keep where the app's quantification folder seats
        
        %variables to store the working images
        random_img;
        background_img;
        calibration_img;
        labelled_img;
        
        px2mm; %store the images' resolution, in # of pixels per mm
        processing_params; % image processing parameters
        
        bubble_diameters; %to store batch processed diameters
    end
    
    
    methods (Access = private)
        
        function param_struct = read_param_file(~,fname)
            param_struct = struct();
            fid = fopen(fname);
            tline = fgetl(fid);
            while ischar(tline)
                if ~isempty(tline)
                    is_comment = strcmp(tline(1),'#');
                    if ~is_comment
                        str_parts = split(tline,', ');
                        param_struct.(str_parts{1}) = str2double(str_parts(2:end));
                    end
                end
                tline = fgetl(fid);
            end
            fclose(fid);
        end
        
        function [x, q, Q] = bubble_psd(app)
            %Get configuration from the app
            xbins = str2double(app.et_NumberofBins.Value);
            data = app.bubble_diameters;
            
            %do calculation. TO DO: use app to perform the chosen calc 
            Dxy = data.^3./data.^2;
            
            %bin data for PDF and then do cumsum for CDF
            [q, edges] = histcounts(Dxy,xbins);  
            x = mean([edges(1:end-1); edges(2:end)],1); %histcounts returns edges, calculate the centres
            Q = cumsum(q)/sum(q);
        end
        
        function write_params(app,dir_path)
            fID = fopen(dir_path + "img_processing_params.csv",'w');
            
            fprintf(fID,"Algorithm,%s\n\n",app.dd_algorithmpick.Value);
            params = app.processing_params;
            fields = fieldnames(params);
            fprintf(fID,"Parameter,Value\n");            
            for i=1:length(fields)
                fprintf(fID,"%s,%1.5f\n",fields{i},params.(fields{i}));
            end
            fclose(fID);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Check where the app is located to then relatively navigate to
            %our quantification foder and look for available algorithms and
            %their parameters
            
            %make sure we use the correct stroke
             if ismac || isunix
                stroke = "/";
            elseif ispc
                stroke = "\";
            end
            %1) Go to the current folder where the app is
             gui_full_path = mfilename("fullpath");             
             %2) From the app go to quantification folder
             path_split = strsplit(gui_full_path,stroke);
             check_remote = extractBetween(gui_full_path,1,3,'Boundaries',"exclusive");
             if check_remote{1} == stroke
                app.quan_folder = join(path_split(1:end-2),filesep); %two folders up
                app.quan_folder = stroke + app.quan_folder{1} + stroke +"quantification" + stroke;   
             else
                app.quan_folder = join(path_split(1:end-2),stroke); %two folders up
                app.quan_folder = app.quan_folder{1} + stroke +"quantification" + stroke;
             end

        end

        % Value changed function: b_FolderSelect
        function b_FolderSelectValueChanged(app, event)
            user_path = uigetdir;        % Calls 'uigetdir' to obtain the directory location from the user
            figure(app.GUI_BubbleAnalyser) %Bring back the app to the front
            if ~user_path
                return
            end
            app.DirectoryLocationLabel.Text = user_path;  % Sets the label text to be the selected path
            
            %Load file names in folder
            folder_files = dir(user_path);
            folder_files = folder_files(~[folder_files.isdir]); %exclude folders
            
            %% Separate image files from whatever else it might be there in the folder
            %figure out the extension if the image files (.jpeg, tiff, png, etc...)
            [~, ~, fextensions] = cellfun(@fileparts, {folder_files.name}, 'UniformOutput', false);
            [s, ~, j] = unique(fextensions);
            img_extension = s{mode(j)};
            %with the extension we can now get the image file list
            if ismac || isunix
                img_files = dir([user_path '/*' img_extension]);
            elseif ispc
                img_files = dir([user_path '\*' img_extension]);
            end
            %img_files = dir([user_path '\*' img_extension]);
            image_names = {img_files(:).name}';         % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
            app.t_images.Data = image_names;            % Displays the directory information to the UITable.
            %Display a random image
            rnd_idx = 2 + randi(length(img_files)-2);
            selected_img = fullfile( user_path, image_names{rnd_idx} );
            app.random_img = imread(selected_img);
            app.im_SampleImg.ImageSource = selected_img;
            if ismac || isunix
                app.working_folder_path = [user_path '/'];  %store the user's pick. Add final stroke
            elseif ispc
                app.working_folder_path = [user_path '\'];  %store the user's pick. Add final stroke
            end
            app.b_Tab1Next.Enable = true;               %Enable Tab1's 'Next' button
        end

        % Cell selection callback: t_images
        function t_imagesCellSelection(app, event)
            selection_idx = event.Indices(1); %get row selection index
            image_names = app.t_images.DisplayData;
            selected_img = fullfile( app.working_folder_path, image_names{selection_idx} );
            app.random_img = imread(selected_img);
            app.im_SampleImg.ImageSource = selected_img;
        end

        % Menu selected function: LicenseMenu
        function LicenseMenuSelected(app, event)
            helpdlg({'Bubble Sizer  Copyright (C) 2021'; ' '; ...
                'Bubble Sizer is free software: you can redistribute it and/or modify'; ...
                'it under the terms of the GNU General Public License as published'; ...
                'by the Free Software Foundation version 3 only of the License'}, ...
                'License');
        end

        % Button pushed function: b_Tab1Next
        function b_Tab1NextPushed(app, event)
            %populate Tab2 default images, i.e. first image in folder is
            %backgound and second is resolution calibration
            %Do this only the first time, don't overwrite if user goes back
            %and forth between tabs
            if ~app.b_Tab2Next.Enable
                image_names = app.t_images.DisplayData;
                background_name = fullfile( app.working_folder_path, image_names{1} );
                app.im_background.ImageSource = background_name;
                app.et_background.Value =  image_names{1};
                
                resolution_img = fullfile( app.working_folder_path, image_names{2} );
                app.im_pxresolution.ImageSource = resolution_img;
                app.et_pxresolution.Value =  image_names{2};
            end
            
            %If the working_files folders doesn't exist, create it
            if ~exist(app.working_folder_path + "working_files"  ,'dir')
                mkdir(app.working_folder_path + "working_files");
            end
            %now change to second tab
            app.TabGroup.SelectedTab = app.ResolutionandbackgroundTab;
        end

        % Menu selected function: WhatisthisMenu_2
        function WhatisthisMenu_2Selected(app, event)
            helpdlg({'Browse folder where images are located'; ' '; ...
                'This will be your working directory'}, ...
                'Find images');
        end

        % Menu selected function: WhatisthisMenu_3
        function WhatisthisMenu_3Selected(app, event)
            helpdlg({'Click on file names to preview';' '}, 'Image file list');
        end

        % Button pushed function: b_Tab2Back
        function b_Tab2BackButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SelectfolderTab;
        end

        % Button pushed function: b_ResolutionConfirm
        function b_ResolutionConfirmButtonPushed(app, event)
            app.calibration_img = imread(app.im_pxresolution.ImageSource);
            h = figure('name',"Choose two points separated by 1.0 cm and press Enter");
            warning off
            imshow(app.calibration_img);
            [x, y, ~] = impixel(); %Get two points separated by 1cm
            close(h);
            warning on
            
            %now calculate pixel resolution
            d = sqrt(diff(x)^2 + diff(y)^2);
            app.px2mm = 10/d; %resolution stored as number of pixels in 10mm
            
            %Enable background correction, which can be optional (but
            %recommended)
            app.BackgroundcorrectionimageLabel.Enable = true;
            app.et_background.Enable = true;
            app.ImagenameLabel_2.Enable = true;
            app.b_BackgroundConfirm.Enable = true;
            app.im_background.Enable = true;
            app.l_Tab2Info.Text = sprintf("Image resolution: %1.2f px/mm\n" + ...
                "\t\tChoose a background correction image (optional, but recommended)"+...
                " Press confirm", 1/app.px2mm);
            app.b_Tab2Next.Enable = true;
        end

        % Button pushed function: b_BackgroundConfirm
        function b_BackgroundConfirmButtonPushed(app, event)
            app.background_img = imread(app.im_background.ImageSource);
            app.l_Tab2Info.Text = "Background correction image selected";
        end

        % Button pushed function: b_Tab2Next
        function b_Tab2NextButtonPushed(app, event)
            app.l_Tab2Info.Text = "Saving current configuration in the working directory...";
            app.b_Tab2Next.Enable = false;

            drawnow
            
            %1) Store some calibration parameters in our working_folder
            if ismac || isunix
                dir_path = app.working_folder_path + "working_files/";
            elseif ispc
                dir_path = app.working_folder_path + "working_files\";
            end
            
            fID = fopen(dir_path+"calibration.csv","w");
            fprintf(fID,"Parameter,Value,Units\n");
            fprintf(fID,"image_resolution, %1.5f,px/mm",1/app.px2mm);
            fclose(fID);
            
            % 2) Save copies of the images used for resolution calibration
            % and background correction
            imwrite(app.calibration_img,dir_path+"Calibration_image.png");
            if ~isempty(app.background_img)
                imwrite(app.background_img,dir_path+"Background_image.png");
            end
            
            % 3) Populate the next Tab with default values
            app.im_sample.ImageSource  = app.random_img;
            app.TabGroup.SelectedTab = app.STEP3Tab;
            value = app.dd_algorithmpick.Value;
            param_file = app.quan_folder + string(value) +".config";
            app.processing_params = read_param_file(app,param_file);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Determine which tab was selected
            selectedTab = app.TabGroup.SelectedTab;
            
            %Check if it is appropiate to change tab
            switch selectedTab.Title
                case 'STEP 1'
                    do_switch = app.b_Tab1Next.Enable;
                case 'STEP 2'
                    do_switch = app.b_Tab2Next.Enable;
                case 'STEP 3'
                    do_switch = app.b_Tab3Next.Enable;
                case 'STEP 4'
                    do_switch = app.b_Tab3Next.Enable;
                otherwise
                    error('Unknown tab: %s.',selectedTab.Title)
            end
            
            %if we can't switch, then do nothing
            if ~do_switch
                app.TabGroup.SelectedTab = event.OldValue;
            end
        end

        % Value changed function: et_pxresolution
        function et_pxresolutionValueChanged(app, event)
            new_img_name = app.et_pxresolution.Value;
            pxresolution_name = fullfile( app.working_folder_path, new_img_name);
            if exist(pxresolution_name,'file')
                app.im_pxresolution.ImageSource = pxresolution_name;
            else
                app.et_pxresolution.Value = event.PreviousValue;
            end
        end

        % Value changed function: et_background
        function et_backgroundValueChanged(app, event)
            new_img_name = app.et_background.Value;
            backgound_name = fullfile( app.working_folder_path, new_img_name);
            if exist(backgound_name,'file')
                app.im_background.ImageSource = backgound_name;
            else
                app.et_background.Value = event.PreviousValue;
            end
        end

        % Button pushed function: BackButtonSTEP3
        function BackButtonSTEP3Pushed(app, event)
            app.TabGroup.SelectedTab = app.ResolutionandbackgroundTab;
        end

        % Button pushed function: b_Tab3ProcessSample
        function b_Tab3ProcessSampleButtonPushed(app, event)
            %1) prepare params for function
            %Check if reading from defaults or from table
            selectedButton = app.rbg_usedefaultparams.SelectedObject;
            use_default = contains(selectedButton.Text,'default');
            if use_default
                params = app.processing_params;
            else
                data = app.t_paramstruct.Data;
                params = cell2struct(data(:,2),data(:,1),1);
            end
            %add extra ones coming from the app
            sample_img = app.random_img;
            params.background_img = app.background_img;
            params.px2mm = app.px2mm;
            
            %2) call selected algorithm
            %got to the quantification folder in case it has not been added
            %to Matlab's path
            old_dir = pwd();
            cd(app.quan_folder);
            algorithm = app.dd_algorithmpick.Value;
            [~, app.labelled_img] = feval(algorithm,sample_img,params);
            cd(old_dir);
            
            %3) update processed image in gui
            app.im_processed.ImageSource = app.labelled_img;
        end

        % Drop down opening function: dd_algorithmpick
        function dd_algorithmpickDropDownOpening(app, event)
            %1) once in the quantification folder, look for .m files
            quan_files = app.quan_folder + "*.m";
            
            %2) Update the drop-down menu with available algorithms
            algorithms = {dir(quan_files).name}; %get names only as cell
            algorithms = cellfun(@(x) x(1:end-2),algorithms,'UniformOutput',false); %remove extension from name
            app.dd_algorithmpick.Items = algorithms;
            
            %3) Load the default paraeters from the first algorithm in the
            %list
            param_file = app.quan_folder + algorithms{1} +".config";
            app.processing_params = read_param_file(app,param_file);
        end

        % Value changed function: dd_algorithmpick
        function dd_algorithmpickValueChanged(app, event)
            value = app.dd_algorithmpick.Value;
            param_file = app.quan_folder + string(value) +".config";
            app.processing_params = read_param_file(app,param_file);
            rbg_usedefaultparamsSelectionChanged(app,event)
        end

        % Selection changed function: rbg_usedefaultparams
        function rbg_usedefaultparamsSelectionChanged(app, event)
            selectedButton = app.rbg_usedefaultparams.SelectedObject;
            use_default = contains(selectedButton.Text,'default');
            
            if use_default
                app.t_paramstruct.Visible = 0;
                app.t_paramstruct.Enable = 'off';
            else
                app.t_paramstruct.Visible = 1;
                app.t_paramstruct.Enable = 'on';
                param_names = fieldnames(app.processing_params);
                param_values = struct2cell(app.processing_params);
                data = [param_names param_values];
                app.t_paramstruct.Data = data;
            end
        end

        % Button pushed function: b_batchprocess
        function b_batchprocessButtonPushed(app, event)
            %Progress bar based on Adam Danz contibution:
            %https://www.mathworks.com/matlabcentral/discussions/highlights/132277-new-in-r2020a-app-button-animation-truecolor-images
            
            %1) Change button name to "Processing"
            app.b_batchprocess.Text = 'Processing...';
            
            %2) Put text on top of icon
            app.b_batchprocess.IconAlignment = 'bottom';
            
            %3) Create waitbar with same color as button
            wbar = permute(repmat(app.b_batchprocess.BackgroundColor,15,1,200),[1,3,2]);
            wbar([1,end],:,:) = 0; % Black frame around waitbar
            wbar(:,[1,end],:) = 0; % Black frame around waitbar
            wbar_color = [237, 164, 60]/255; %scaled RGB of the bar
            
            %4) Load the empty waitbar to the button
            app.b_batchprocess.Icon = wbar;
            drawnow
            
            %5) Loop through the images in the selected folder
            selectedButton = app.rbg_usedefaultparams.SelectedObject;
            use_default = contains(selectedButton.Text,'default');
            if use_default
                params = app.processing_params;
            else
                data = app.t_paramstruct.Data;
                params = cell2struct(data(:,2),data(:,1),1);
            end
            %add extra ones coming from the app
            params.background_img = app.background_img;
            params.px2mm = app.px2mm;
            
            N_images = length(app.t_images.Data);
            image_names = app.t_images.Data;
            old_dir = pwd();
            cd(app.quan_folder);
            has_background = ~isempty(app.background_img);
            D = [];
            for i = 1:N_images
                is_resol_img = strcmp(app.et_pxresolution.Value,image_names{i});
                is_bkgnd_img = strcmp(app.et_background.Value,image_names{i});
                if is_resol_img || (is_bkgnd_img&&has_background)
                    continue
                end
                selected_img = fullfile( app.working_folder_path, image_names{i} );
                I = imread(selected_img);
                
                %use our processing algorithm
                algorithm = app.dd_algorithmpick.Value;
                [img_diameters, app.labelled_img] = feval(algorithm,I,params);
                D = [D; img_diameters]; %Maybe think of pre-allocating a huge array rather than grow it
                
                currentProg = min(round((size(wbar,2)-2)*(i/N_images)),size(wbar,2)-2);
                app.b_batchprocess.Icon(2:end-1, 2:currentProg+1, 1) = wbar_color(1);
                app.b_batchprocess.Icon(2:end-1, 2:currentProg+1, 2) = wbar_color(2);
                app.b_batchprocess.Icon(2:end-1, 2:currentProg+1, 3) = wbar_color(3);
                drawnow;
            end
            cd(old_dir);
            app.bubble_diameters = D;
            
            %6) remove waitbar
            app.b_batchprocess.Icon = '';
            
            %7) Change button name
            app.b_batchprocess.Text = 'Batch process images';
            app.l_Tab2Info.Text = "Press Next to see results...";
            app.b_Tab3Next.Enable = true;
        end

        % Button pushed function: b_Tab3Next
        function b_Tab3NextButtonPushed(app, event)
            %1 Store in the working directory the params used for batch
            %processing
            if ismac || isunix
                dir_path = app.working_folder_path + "working_files/";
            elseif ispc
                dir_path = app.working_folder_path + "working_files\";
            end
            write_params(app, dir_path);            
            
            %plot results
            [x,q,Q] = bubble_psd(app); %calculate the Dxy chose by the user            
            hold(app.ax_histogram, 'on');
            
            % Activate the left side of the axes
            yyaxis(app.ax_histogram,'left');
            bar(app.ax_histogram,x,q,1); %bar width = 1
            ylabel(app.ax_histogram, 'q [#]');
            set(app.ax_histogram, 'YColor',[0 0 0]);
            
            % Activate the right side of the axes
            yyaxis(app.ax_histogram,'right');
            dx = (x(2)-x(1))/2;
            plot(app.ax_histogram,x+dx,Q,'MarkerSize',7,'Marker','square','LineWidth',1.5,...
                'Color',[1 0 0]);
            ylabel(app.ax_histogram, 'Q [%]');
            set(app.ax_histogram, 'YColor',[0 0 0]);
            
            % Set the remaining axes properties
            set(app.ax_histogram,'XGrid','on','XMinorTick','on','YGrid','on');
            xlabel(app.ax_histogram, "d32 [mm]");
            app.TabGroup.SelectedTab = app.ResultsTab;
        end

        % Button pushed function: BackButtonSTEP4
        function BackButtonSTEP4Pushed(app, event)
            app.TabGroup.SelectedTab = app.STEP3Tab;
        end

        % Value changed function: b_ResolutionSelect_2
        function b_ResolutionSelect_2ValueChanged(app, event)

            selected_img = uigetfile('*.*');    %Select imagen used for calibration  
            resolution_img = fullfile( app.working_folder_path, selected_img );
            app.im_pxresolution.ImageSource = resolution_img;
            app.et_pxresolution.Value =  selected_img;

            app.calibration_img = imread(app.im_pxresolution.ImageSource);
            h = figure('name',"Choose two points separated by 1.0 cm and press Enter");
            warning off
            imshow(app.calibration_img);
            [x, y, ~] = impixel(); %Get two points separated by 1cm
            close(h);
            warning on
            
            %now calculate pixel resolution
            d = sqrt(diff(x)^2 + diff(y)^2);
            app.px2mm = 10/d; %resolution stored as number of pixels in 10mm
            
            %Enable background correction, which can be optional (but
            %recommended)
            app.BackgroundcorrectionimageLabel.Enable = true;
            app.et_background.Enable = true;
            app.ImagenameLabel_2.Enable = true;
            app.b_BackgroundConfirm.Enable = true;
            app.im_background.Enable = true;
            app.b_BackgroundSelect_3.Enable = true;
            app.orLabel_3.Enable = true;
            app.l_Tab2Info.Text = sprintf("Image resolution: %1.2f px/mm\n" + ...
                "\t\tChoose a background correction image (optional, but recommended)"+...
                " Press confirm", 1/app.px2mm);
            app.b_Tab2Next.Enable = true;
        
        end

        % Value changed function: b_BackgroundSelect_3
        function b_BackgroundSelect_3ValueChanged(app, event)
            
                selected_img = uigetfile('*.*');    %Select imagen for background
                background_name = fullfile( app.working_folder_path, selected_img );
                app.im_background.ImageSource = background_name;
                app.et_background.Value =  selected_img;         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI_BubbleAnalyser = uifigure('Visible', 'off');
            app.GUI_BubbleAnalyser.Name = 'Bubble Analyser';
            app.GUI_BubbleAnalyser.Icon = 'icon.png';
            app.FileMenu = uimenu(app.GUI_BubbleAnalyser);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.OpenfileMenu = uimenu(app.FileMenu);
            app.OpenfileMenu.Text = 'Open file';
            app.ChoosefolderMenu = uimenu(app.OpenfileMenu);
            app.ChoosefolderMenu.Text = 'Choose folder';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.matformatMenu = uimenu(app.ExportMenu);
            app.matformatMenu.Text = '.mat format';
            app.csvformatMenu = uimenu(app.ExportMenu);
            app.csvformatMenu.Text = '.csv format';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.Text = 'Close';
            app.EditMenu = uimenu(app.GUI_BubbleAnalyser);
            app.EditMenu.Text = 'Edit';
            app.UndoMenu = uimenu(app.EditMenu);
            app.UndoMenu.Text = 'Undo';
            app.RedoMenu = uimenu(app.EditMenu);
            app.RedoMenu.Text = 'Redo';
            app.CutMenu = uimenu(app.EditMenu);
            app.CutMenu.Text = 'Cut';
            app.CopyMenu = uimenu(app.EditMenu);
            app.CopyMenu.Text = 'Copy';
            app.PasteMenu = uimenu(app.EditMenu);
            app.PasteMenu.Text = 'Paste';
            app.ViewMenu = uimenu(app.GUI_BubbleAnalyser);
            app.ViewMenu.Text = 'View';
            app.PreviousMenu = uimenu(app.ViewMenu);
            app.PreviousMenu.Text = 'Previous';
            app.NextMenu = uimenu(app.ViewMenu);
            app.NextMenu.Text = 'Next';
            app.WindowMenu = uimenu(app.GUI_BubbleAnalyser);
            app.WindowMenu.Text = 'Window';
            app.MinimiseMenu = uimenu(app.WindowMenu);
            app.MinimiseMenu.Text = 'Minimise';
            app.FullscreenMenu = uimenu(app.WindowMenu);
            app.FullscreenMenu.Text = 'Full screen';
            app.ZoomMenu = uimenu(app.WindowMenu);
            app.ZoomMenu.Text = 'Zoom';
            app.HelpMenu = uimenu(app.GUI_BubbleAnalyser);
            app.HelpMenu.Text = 'Help';
            app.LicenseMenu = uimenu(app.HelpMenu);
            app.LicenseMenu.MenuSelectedFcn = createCallbackFcn(app, @LicenseMenuSelected, true);
            app.LicenseMenu.Text = 'License';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.Text = 'Documentation';
            app.TabGroup = uitabgroup(app.GUI_BubbleAnalyser);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SelectfolderTab = uitab(app.TabGroup);
            app.SelectfolderTab.Title = 'Select folder';
            app.b_FolderSelect = uibutton(app.SelectfolderTab, 'state');
            app.b_FolderSelect.ValueChangedFcn = createCallbackFcn(app, @b_FolderSelectValueChanged, true);
            app.b_FolderSelect.Tooltip = {'Browse folder where images are located. '};
            app.b_FolderSelect.Text = 'Select folder';
            app.DirectoryLocationLabel = uilabel(app.SelectfolderTab);
            app.DirectoryLocationLabel.Tooltip = {'This will be your working directory.'};
            app.DirectoryLocationLabel.Text = 'Directory location';
            app.t_images = uitable(app.SelectfolderTab);
            app.t_images.ColumnName = {'Select a photo of interest to test the algorithm in steps 3 and 4'};
            app.t_images.RowName = {};
            app.t_images.CellSelectionCallback = createCallbackFcn(app, @t_imagesCellSelection, true);
            app.t_images.Tooltip = {'Click on file names to preview.'};
            app.im_SampleImg = uiimage(app.SelectfolderTab);
            app.im_SampleImg.Tooltip = {''};
            app.im_SampleImg.ImageSource = 'Bubbles_example.PNG';
            app.b_Tab1Next = uibutton(app.SelectfolderTab, 'push');
            app.b_Tab1Next.ButtonPushedFcn = createCallbackFcn(app, @b_Tab1NextPushed, true);
            app.b_Tab1Next.Enable = 'off';
            app.b_Tab1Next.Text = 'Next >>';
            app.ImagepreviewLabel = uilabel(app.SelectfolderTab);
            app.ImagepreviewLabel.Text = 'Image preview';
            app.Label_2 = uilabel(app.SelectfolderTab);
            app.Label_2.Text = 'All photos in this folder will be analysed in the last step';
            app.ResolutionandbackgroundTab = uitab(app.TabGroup);
            app.ResolutionandbackgroundTab.Title = 'Resolution and background';
            app.b_Tab2Next = uibutton(app.ResolutionandbackgroundTab, 'push');
            app.b_Tab2Next.ButtonPushedFcn = createCallbackFcn(app, @b_Tab2NextButtonPushed, true);
            app.b_Tab2Next.Enable = 'off';
            app.b_Tab2Next.Text = 'Next >>';
            app.b_Tab2Back = uibutton(app.ResolutionandbackgroundTab, 'push');
            app.b_Tab2Back.ButtonPushedFcn = createCallbackFcn(app, @b_Tab2BackButtonPushed, true);
            app.b_Tab2Back.Text = '<< Back';
            app.im_pxresolution = uiimage(app.ResolutionandbackgroundTab);
            app.im_pxresolution.Tooltip = {'Preview of the calibration photo'};
            app.im_pxresolution.ImageSource = 'PixelCalibration_example.PNG';
            app.im_background = uiimage(app.ResolutionandbackgroundTab);
            app.im_background.Enable = 'off';
            app.im_background.ImageSource = 'Background_example.PNG';
            app.b_ResolutionConfirm = uibutton(app.ResolutionandbackgroundTab, 'push');
            app.b_ResolutionConfirm.ButtonPushedFcn = createCallbackFcn(app, @b_ResolutionConfirmButtonPushed, true);
            app.b_ResolutionConfirm.Tooltip = {'Confirm the calibration photo and choose two points separated by 1.0 cm. Then, press ENTER'};
            app.b_ResolutionConfirm.Text = 'Confirm';
            app.b_BackgroundConfirm = uibutton(app.ResolutionandbackgroundTab, 'push');
            app.b_BackgroundConfirm.ButtonPushedFcn = createCallbackFcn(app, @b_BackgroundConfirmButtonPushed, true);
            app.b_BackgroundConfirm.Enable = 'off';
            app.b_BackgroundConfirm.Text = 'Confirm';
            app.PixelresolutioncalibrationLabel = uilabel(app.ResolutionandbackgroundTab);
            app.PixelresolutioncalibrationLabel.Text = 'Pixel resolution calibration';
            app.BackgroundcorrectionimageLabel = uilabel(app.ResolutionandbackgroundTab);
            app.BackgroundcorrectionimageLabel.Enable = 'off';
            app.BackgroundcorrectionimageLabel.Text = 'Background correction image';
            app.ImagenameLabel = uilabel(app.ResolutionandbackgroundTab);
            app.ImagenameLabel.Text = 'Image name';
            app.et_pxresolution = uieditfield(app.ResolutionandbackgroundTab, 'text');
            app.et_pxresolution.ValueChangedFcn = createCallbackFcn(app, @et_pxresolutionValueChanged, true);
            app.et_pxresolution.Tooltip = {'Enter the name of the calibration photo manually. (Example: IMG_01.JPG)'};
            app.orLabel = uilabel(app.ResolutionandbackgroundTab);
            app.orLabel.Text = 'or:';
            app.et_background = uieditfield(app.ResolutionandbackgroundTab, 'text');
            app.et_background.ValueChangedFcn = createCallbackFcn(app, @et_backgroundValueChanged, true);
            app.et_background.Enable = 'off';
            app.et_background.Tooltip = {'Enter the name of the background photo manually. (Example: IMG_02.JPG)'};
            app.b_ManualResolution = uibutton(app.ResolutionandbackgroundTab, 'push');
            app.b_ManualResolution.Tooltip = {'Enter calibration resolution manually in px/mm'};
            app.b_ManualResolution.Text = 'Calibrate resolution manually';
            app.l_Tab2Info = uilabel(app.ResolutionandbackgroundTab);
            app.l_Tab2Info.Text = 'Confirm, or modify accordingly, the images for resolution calibration ';
            app.ImagenameLabel_2 = uilabel(app.ResolutionandbackgroundTab);
            app.ImagenameLabel_2.Enable = 'off';
            app.ImagenameLabel_2.Text = 'Image name';
            app.b_ResolutionSelect_2 = uibutton(app.ResolutionandbackgroundTab, 'state');
            app.b_ResolutionSelect_2.ValueChangedFcn = createCallbackFcn(app, @b_ResolutionSelect_2ValueChanged, true);
            app.b_ResolutionSelect_2.Tooltip = {'Select the calibration photo from a folder'};
            app.b_ResolutionSelect_2.Text = 'Select other image for resolution calibration';
            app.orLabel_2 = uilabel(app.ResolutionandbackgroundTab);
            app.orLabel_2.Text = 'or:';
            app.b_BackgroundSelect_3 = uibutton(app.ResolutionandbackgroundTab, 'state');
            app.b_BackgroundSelect_3.ValueChangedFcn = createCallbackFcn(app, @b_BackgroundSelect_3ValueChanged, true);
            app.b_BackgroundSelect_3.Enable = 'off';
            app.b_BackgroundSelect_3.Tooltip = {'Select the background photo from a folder'};
            app.b_BackgroundSelect_3.Text = 'Select other image for background correction';
            app.orLabel_3 = uilabel(app.ResolutionandbackgroundTab);
            app.orLabel_3.Enable = 'off';
            app.orLabel_3.Text = 'or:';
            app.PreviewLabel = uilabel(app.ResolutionandbackgroundTab);
            app.PreviewLabel.Text = 'Preview';
            app.PreviewLabel_2 = uilabel(app.ResolutionandbackgroundTab);
            app.PreviewLabel_2.Text = 'Preview';
            app.STEP3Tab = uitab(app.TabGroup);
            app.STEP3Tab.Title = 'STEP 3';
            app.im_processed = uiimage(app.STEP3Tab);
            app.im_processed.ImageSource = 'BatchProcess_example.PNG';
            app.im_sample = uiimage(app.STEP3Tab);
            app.BackButtonSTEP3 = uibutton(app.STEP3Tab, 'push');
            app.BackButtonSTEP3.ButtonPushedFcn = createCallbackFcn(app, @BackButtonSTEP3Pushed, true);
            app.BackButtonSTEP3.Text = '<< Back';
            app.b_Tab3Next = uibutton(app.STEP3Tab, 'push');
            app.b_Tab3Next.ButtonPushedFcn = createCallbackFcn(app, @b_Tab3NextButtonPushed, true);
            app.b_Tab3Next.Enable = 'off';
            app.b_Tab3Next.Text = 'Next >>';
            app.b_Tab3ProcessSample = uibutton(app.STEP3Tab, 'push');
            app.b_Tab3ProcessSample.ButtonPushedFcn = createCallbackFcn(app, @b_Tab3ProcessSampleButtonPushed, true);
            app.b_Tab3ProcessSample.Text = 'Confirm';
            app.rbg_usedefaultparams = uibuttongroup(app.STEP3Tab);
            app.rbg_usedefaultparams.SelectionChangedFcn = createCallbackFcn(app, @rbg_usedefaultparamsSelectionChanged, true);
            app.rbg_usedefaultparams.Title = 'Image processing sandbox';
            app.UsingdefaultparametersButton = uiradiobutton(app.rbg_usedefaultparams);
            app.UsingdefaultparametersButton.Text = 'Using default parameters';
            app.UsingdefaultparametersButton.Value = true;
            app.ChoosingmyownparametersButton = uiradiobutton(app.rbg_usedefaultparams);
            app.ChoosingmyownparametersButton.Text = 'Choosing my own parameters';
            app.b_batchprocess = uibutton(app.STEP3Tab, 'push');
            app.b_batchprocess.ButtonPushedFcn = createCallbackFcn(app, @b_batchprocessButtonPushed, true);
            app.b_batchprocess.Text = 'Batch process images';
            app.SampleimageLabel = uilabel(app.STEP3Tab);
            app.SampleimageLabel.Text = 'Sample image';
            app.ProcessedimageLabel = uilabel(app.STEP3Tab);
            app.ProcessedimageLabel.Text = 'Processed image';
            app.l_Tab3Info = uilabel(app.STEP3Tab);
            app.l_Tab3Info.Text = {'Choose an image processing algorithm from the dropdown menu.'; 'Tweak parameters as desired and check results using the Confirm button'};
            app.t_paramstruct = uitable(app.STEP3Tab);
            app.t_paramstruct.ColumnName = {'Parameter'; 'Value'};
            app.t_paramstruct.RowName = {};
            app.t_paramstruct.ColumnEditable = [false true];
            app.t_paramstruct.Enable = 'off';
            app.t_paramstruct.Visible = 'off';
            app.dd_algorithmpick = uidropdown(app.STEP3Tab);
            app.dd_algorithmpick.Items = {'BV_quantification'};
            app.dd_algorithmpick.DropDownOpeningFcn = createCallbackFcn(app, @dd_algorithmpickDropDownOpening, true);
            app.dd_algorithmpick.ValueChangedFcn = createCallbackFcn(app, @dd_algorithmpickValueChanged, true);
            app.dd_algorithmpick.Value = 'BV_quantification';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.b_Tab4Next = uibutton(app.ResultsTab, 'push');
            app.b_Tab4Next.Enable = 'off';
            app.b_Tab4Next.Text = 'Save graph';
            app.BackButtonSTEP4 = uibutton(app.ResultsTab, 'push');
            app.BackButtonSTEP4.ButtonPushedFcn = createCallbackFcn(app, @BackButtonSTEP4Pushed, true);
            app.BackButtonSTEP4.Text = '<< Back';
            app.cb_CDF = uicheckbox(app.ResultsTab);
            app.cb_CDF.Text = 'CDF';
            app.cb_CDF.Value = true;
            app.cb_PDF = uicheckbox(app.ResultsTab);
            app.cb_PDF.Text = 'PDF';
            app.cb_PDF.Value = true;
            app.dmeanCheckBox = uicheckbox(app.ResultsTab);
            app.dmeanCheckBox.Text = 'd mean';
            app.cb_d32 = uicheckbox(app.ResultsTab);
            app.cb_d32.Text = 'd32';
            app.cb_d32.Value = true;
            app.SelectoutputsLabel = uilabel(app.ResultsTab);
            app.SelectoutputsLabel.Text = 'Select output(s):';
            app.NumberofbinsEditField_2Label = uilabel(app.ResultsTab);
            app.NumberofbinsEditField_2Label.Text = 'Number of bins';
            app.et_NumberofBins = uieditfield(app.ResultsTab, 'text');
            app.et_NumberofBins.Value = '15';
            app.dxyCheckBox = uicheckbox(app.ResultsTab);
            app.dxyCheckBox.Text = 'dxy';
            app.xEditFieldLabel = uilabel(app.ResultsTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.ResultsTab, 'text');
            app.yEditFieldLabel = uilabel(app.ResultsTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.ResultsTab, 'text');
            app.ResultsLabel = uilabel(app.ResultsTab);
            app.ResultsLabel.Text = 'Results:';
            app.rbg_HistogramBy = uibuttongroup(app.ResultsTab);
            app.rbg_HistogramBy.Title = 'Histogram by:';
            app.NumberButton = uiradiobutton(app.rbg_HistogramBy);
            app.NumberButton.Text = 'Number';
            app.NumberButton.Value = true;
            app.VolumeButton = uiradiobutton(app.rbg_HistogramBy);
            app.VolumeButton.Text = 'Volume';
            app.Averaged32Label = uilabel(app.ResultsTab);
            app.Averaged32Label.Text = 'Average d32 = ';
            app.ax_histogram = uiaxes(app.ResultsTab);
            title(app.ax_histogram, 'Histogram preview')
            xlabel(app.ax_histogram, 'X')
            ylabel(app.ax_histogram, 'Y')
            zlabel(app.ax_histogram, 'Z')
            app.cm_StartAnalysis = uicontextmenu(app.GUI_BubbleAnalyser);
            app.WhatisthisMenu = uimenu(app.cm_StartAnalysis);
            app.WhatisthisMenu.Text = 'What is this?';
            app.cm_FolderSelect = uicontextmenu(app.GUI_BubbleAnalyser);
            % Assign app.cm_FolderSelect
            app.b_ResolutionSelect_2.ContextMenu = app.cm_FolderSelect;
            app.b_BackgroundSelect_3.ContextMenu = app.cm_FolderSelect;
            app.WhatisthisMenu_2 = uimenu(app.cm_FolderSelect);
            app.WhatisthisMenu_2.MenuSelectedFcn = createCallbackFcn(app, @WhatisthisMenu_2Selected, true);
            app.WhatisthisMenu_2.Text = 'What is this?';
            app.cm_TableImages = uicontextmenu(app.GUI_BubbleAnalyser);
            app.WhatisthisMenu_3 = uimenu(app.cm_TableImages);
            app.WhatisthisMenu_3.MenuSelectedFcn = createCallbackFcn(app, @WhatisthisMenu_3Selected, true);
            app.WhatisthisMenu_3.Text = 'What is this?';
            % Show the figure after all components are created
            app.GUI_BubbleAnalyser.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI_BubbleAnalyser)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI_BubbleAnalyser)
        end
    end
end